classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        StartLabel      matlab.ui.control.Label
        EndLabel        matlab.ui.control.Label
        originXLabel    matlab.ui.control.Label
        originYLabel    matlab.ui.control.Label
        SUBMITButton    matlab.ui.control.Button
        XLabel          matlab.ui.control.Label
        YLabel          matlab.ui.control.Label
        RoadLabel       matlab.ui.control.Label
        Start_x         matlab.ui.control.NumericEditField
        Start_y         matlab.ui.control.NumericEditField
        StartRoadX      matlab.ui.control.NumericEditField
        End_x           matlab.ui.control.NumericEditField
        End_y           matlab.ui.control.NumericEditField
        EndRoadX        matlab.ui.control.NumericEditField
        StartRoadY      matlab.ui.control.NumericEditField
        EndRoadY        matlab.ui.control.NumericEditField
        StartRoadNum    matlab.ui.control.NumericEditField
        EndRoadNum      matlab.ui.control.NumericEditField
        SUBMITButton_2  matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             assignin('base','app',app);
            map = figure('Name','mapViwer','color','green');
            figure(map);
            hold on
            axis([-180 180 -50 150]);
            axis equal;
        end

        % Button pushed function: SUBMITButton
        function SUBMITButtonPushed(app, event)
            [app.StartRoadNum.Value,app.StartRoadX.Value,app.StartRoadY.Value] = pointBelongs(app.Start_x.Value,app.Start_y.Value);          
        end

        % Button pushed function: SUBMITButton_2
        function SUBMITButton_2Pushed(app, event)
              [app.EndRoadNum.Value,app.EndRoadX.Value,app.EndRoadY.Value] = pointBelongs(app.End_x.Value,app.End_y.Value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartLabel = uilabel(app.UIFigure);
            app.StartLabel.Text = 'Start';
            app.EndLabel = uilabel(app.UIFigure);
            app.EndLabel.Text = 'End';
            app.originXLabel = uilabel(app.UIFigure);
            app.originXLabel.Text = 'originX';
            app.originYLabel = uilabel(app.UIFigure);
            app.originYLabel.Text = 'originY';
            app.SUBMITButton = uibutton(app.UIFigure, 'push');
            app.SUBMITButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITButtonPushed, true);
            app.SUBMITButton.Text = 'SUBMIT';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.RoadLabel = uilabel(app.UIFigure);
            app.RoadLabel.Text = 'Road';
            app.Start_x = uieditfield(app.UIFigure, 'numeric');
            app.Start_y = uieditfield(app.UIFigure, 'numeric');
            app.StartRoadX = uieditfield(app.UIFigure, 'numeric');
            app.StartRoadX.Editable = 'off';
            app.End_x = uieditfield(app.UIFigure, 'numeric');
            app.End_y = uieditfield(app.UIFigure, 'numeric');
            app.EndRoadX = uieditfield(app.UIFigure, 'numeric');
            app.EndRoadX.Editable = 'off';
            app.StartRoadY = uieditfield(app.UIFigure, 'numeric');
            app.StartRoadY.Editable = 'off';
            app.EndRoadY = uieditfield(app.UIFigure, 'numeric');
            app.EndRoadY.Editable = 'off';
            app.StartRoadNum = uieditfield(app.UIFigure, 'numeric');
            app.StartRoadNum.Editable = 'off';
            app.EndRoadNum = uieditfield(app.UIFigure, 'numeric');
            app.EndRoadNum.Editable = 'off';
            app.SUBMITButton_2 = uibutton(app.UIFigure, 'push');
            app.SUBMITButton_2.ButtonPushedFcn = createCallbackFcn(app, @SUBMITButton_2Pushed, true);
            app.SUBMITButton_2.Text = 'SUBMIT';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end