classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        xdorFileEditFieldLabel  matlab.ui.control.Label
        xdorFileEditField       matlab.ui.control.EditField
        plotButton              matlab.ui.control.Button
        PointPlotinMapPanel     matlab.ui.container.Panel
        STARTLabel              matlab.ui.control.Label
        ENDLabel                matlab.ui.control.Label
        XLabel                  matlab.ui.control.Label
        YLabel                  matlab.ui.control.Label
        X_in_MapLabel           matlab.ui.control.Label
        Y_in_MapLabel           matlab.ui.control.Label
        RoadNumLabel            matlab.ui.control.Label
        startx                  matlab.ui.control.NumericEditField
        endx                    matlab.ui.control.NumericEditField
        starty                  matlab.ui.control.NumericEditField
        endy                    matlab.ui.control.NumericEditField
        startRoadNum            matlab.ui.control.NumericEditField
        endRoadNum              matlab.ui.control.NumericEditField
        startXmap               matlab.ui.control.NumericEditField
        endXmap                 matlab.ui.control.NumericEditField
        startYmap               matlab.ui.control.NumericEditField
        endYmap                 matlab.ui.control.NumericEditField
        submitButton            matlab.ui.control.Button
        GeneratePathPlanPanel   matlab.ui.container.Panel
        Button                  matlab.ui.control.Button
        StatusLampLabel         matlab.ui.control.Label
        StatusLamp              matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        openDriveObj; % Description
    end
    

    methods (Access = private)

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
         fileObj = xml2struct(app.xdorFileEditField.Value);
         app.openDriveObj = fileObj.OpenDRIVE;
         openDrivePlot2(app.openDriveObj,app.UIAxes);
         app.xdorFileEditField.Editable = false;      
        end

        % Button pushed function: submitButton
        function submitButtonPushed(app, event)
            [app.startRoadNum.Value,app.startXmap.Value,app.startYmap.Value] = pointBelongs1(app.openDriveObj,app.startx.Value,app.starty.Value,app.UIAxes);
            [app.endRoadNum.Value,app.endXmap.Value,app.endYmap.Value] = pointBelongs1(app.openDriveObj,app.endx.Value,app.endy.Value,app.UIAxes);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            app.StatusLamp.Color=[0,1,0];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Map')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-180 180];
            app.UIAxes.YLim = [-50 150];
            app.UIAxes.XTick = [-150 -100 -50 0 50 100 150];
            app.UIAxes.YTick = [-50 0 50 100 150];
            app.xdorFileEditFieldLabel = uilabel(app.Panel);
            app.xdorFileEditFieldLabel.Text = 'xdorFile';
            app.xdorFileEditField = uieditfield(app.Panel, 'text');
            app.plotButton = uibutton(app.Panel, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.PointPlotinMapPanel = uipanel(app.UIFigure);
            app.PointPlotinMapPanel.Title = 'Point Plot in Map';
            app.STARTLabel = uilabel(app.PointPlotinMapPanel);
            app.STARTLabel.Text = 'START';
            app.ENDLabel = uilabel(app.PointPlotinMapPanel);
            app.ENDLabel.Text = 'END';
            app.XLabel = uilabel(app.PointPlotinMapPanel);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.PointPlotinMapPanel);
            app.YLabel.Text = 'Y';
            app.X_in_MapLabel = uilabel(app.PointPlotinMapPanel);
            app.X_in_MapLabel.Text = 'X_in_Map';
            app.Y_in_MapLabel = uilabel(app.PointPlotinMapPanel);
            app.Y_in_MapLabel.Text = 'Y_in_Map';
            app.RoadNumLabel = uilabel(app.PointPlotinMapPanel);
            app.RoadNumLabel.Text = 'RoadNum';
            app.startx = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.endx = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.starty = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.endy = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.startRoadNum = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.startRoadNum.Editable = 'off';
            app.endRoadNum = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.endRoadNum.Editable = 'off';
            app.startXmap = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.startXmap.Editable = 'off';
            app.endXmap = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.endXmap.Editable = 'off';
            app.startYmap = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.startYmap.Editable = 'off';
            app.endYmap = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.endYmap.Editable = 'off';
            app.submitButton = uibutton(app.PointPlotinMapPanel, 'push');
            app.submitButton.ButtonPushedFcn = createCallbackFcn(app, @submitButtonPushed, true);
            app.submitButton.Text = 'submit';
            app.GeneratePathPlanPanel = uipanel(app.UIFigure);
            app.GeneratePathPlanPanel.Title = 'Generate Path Plan';
            app.Button = uibutton(app.GeneratePathPlanPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.StatusLampLabel = uilabel(app.GeneratePathPlanPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.GeneratePathPlanPanel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end