classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        xdorFileEditFieldLabel  matlab.ui.control.Label
        xdorFileEditField       matlab.ui.control.EditField
        plotButton              matlab.ui.control.Button
        PointPlotinMapPanel     matlab.ui.container.Panel
        STARTLabel              matlab.ui.control.Label
        ENDLabel                matlab.ui.control.Label
        XLabel                  matlab.ui.control.Label
        YLabel                  matlab.ui.control.Label
        X_in_MapLabel           matlab.ui.control.Label
        Y_in_MapLabel           matlab.ui.control.Label
        RoadNumLabel            matlab.ui.control.Label
        startx                  matlab.ui.control.NumericEditField
        endx                    matlab.ui.control.NumericEditField
        starty                  matlab.ui.control.NumericEditField
        endy                    matlab.ui.control.NumericEditField
        startRoadNum            matlab.ui.control.NumericEditField
        endRoadNum              matlab.ui.control.NumericEditField
        startXmap               matlab.ui.control.NumericEditField
        endXmap                 matlab.ui.control.NumericEditField
        startYmap               matlab.ui.control.NumericEditField
        endYmap                 matlab.ui.control.NumericEditField
        submitButton            matlab.ui.control.Button
        GeoNumLabel             matlab.ui.control.Label
        LaneNumLabel            matlab.ui.control.Label
        startGeoNum             matlab.ui.control.NumericEditField
        startLaneNum            matlab.ui.control.NumericEditField
        endGeoNum               matlab.ui.control.NumericEditField
        endLaneNum              matlab.ui.control.NumericEditField
        GeneratePathPlanPanel   matlab.ui.container.Panel
        Button                  matlab.ui.control.Button
        StatusLampLabel         matlab.ui.control.Label
        StatusLamp              matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        openDriveObj; % Gernerated from File
        mstartPoint;  %start point in Map
        mendPoint;   % end point in Map
        roadNet;
        
        
        sCache;
        eCache;
%         sPHandler;  % start point Handler
%         sLhandler; % start line Handler
%         ePHandler; % end point Handler
%         eLHandler; % end point Handler
        
        path;
    end
    

    methods (Access = private)

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
         fileObj = xml2struct(app.xdorFileEditField.Value);
         app.openDriveObj = fileObj.OpenDRIVE;
         app.roadNet = roadNetParse(app.openDriveObj);
         openDrivePlot4(app.openDriveObj,app.UIAxes);
         app.xdorFileEditField.Editable = false;      
        end

        % Button pushed function: submitButton
        function submitButtonPushed(app, event)
            msg1 = "Start Information Invalid";
            msg2 = "End  Information Invalid";
%             if ~isempty(app.sPHandler)||~isempty(app.sLhandler)||~isempty(app.ePHandler)||~isempty(app.eLHandler)
%                 delete(app.sPHandler);
%                 delete(app.sLhandler);
%                 delete(app.ePHandler);
%                 delete(app.eLHandler);
%                 
%             end


            if ~isempty(app.sCache)||~isempty(app.eCache)
                delete(app.sCache);
                delete(app.eCache);
            end

            if app.startx.Value==0 && app.starty.Value==0
                uialert(app.UIFigure,msg1,'Warning','Icon','warning');
            else                
%                 [app.startRoadNum.Value,app.startXmap.Value,app.startYmap.Value,app.startGeoNum.Value,app.startLaneNum.Value] = pointBelongs1(app.openDriveObj,app.startx.Value,app.starty.Value,app.UIAxes);
                [app.mstartPoint,app.sCache] = pointBelongs1(app.openDriveObj,app.startx.Value,app.starty.Value,app.UIAxes);
%                                 [app.mstartPoint,app.sPHandler,app.sLhandler] = pointBelongs1(app.openDriveObj,app.startx.Value,app.starty.Value,app.UIAxes);

                app.startRoadNum.Value = app.mstartPoint.RoadNum;
                app.startXmap.Value = app.mstartPoint.Roadx;
                app.startYmap.Value = app.mstartPoint.Roady;
                app.startGeoNum.Value = app.mstartPoint.GeoNum;
                app.startLaneNum.Value = app.mstartPoint.LaneNum;
            end
            
            if app.endx.Value==0 && app.endy.Value==0
                uialert(app.UIFigure,msg2,'Warning','Icon','warning');
            else
%                   [app.endRoadNum.Value,app.endXmap.Value,app.endYmap.Value,app.endGeoNum.Value,app.endLaneNum.Value] = pointBelongs1(app.openDriveObj,app.endx.Value,app.endy.Value,app.UIAxes);
                [app.mendPoint,app.eCache] = pointBelongs1(app.openDriveObj,app.endx.Value,app.endy.Value,app.UIAxes);
%                   [app.mendPoint,app.ePHandler,app.eLHandler] = pointBelongs1(app.openDriveObj,app.endx.Value,app.endy.Value,app.UIAxes);
                app.endRoadNum.Value = app.mendPoint.RoadNum;
                app.endXmap.Value = app.mendPoint.Roadx;
                app.endYmap.Value = app.mendPoint.Roady;
                app.endGeoNum.Value = app.mendPoint.GeoNum;
                app.endLaneNum.Value = app.mendPoint.LaneNum;
            end          
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
%             startPoint.x = app.startXmap.Value;
%             startPoint.y = app.startYmap.Value;
%             startPoint.RoadNum = app.startGeoNum.Value;
%             startPoint.GeoNum = app.startGeoNum.Value;
%             startPoint.LaneNum = app.startLaneNum.Value;
%             endPoint.x = app.endXmap.Value;
%             endPoint.y = app.endYmap.Value;
%             endPoint.RoadNum = app.endRoadNum.Value;
%             endPoint.GeoNum = app.endGeoNum.Value;
%             endPoint.LaneNum = app.endLaneNum.Value;
%             if ~isempty(app.path) && exist ('app.path','var')
            if ~isempty(app.path) 
                delete(app.path);              
            end
            app.path = [];
            app.path=pathPlan1(app.mstartPoint,app.mendPoint,app.UIAxes,app.openDriveObj);
            app.StatusLamp.Color=[0,1,0];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Map')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [400 1300];
            app.UIAxes.YLim = [-150 450];
            app.xdorFileEditFieldLabel = uilabel(app.Panel);
            app.xdorFileEditFieldLabel.Text = 'xdorFile';
            app.xdorFileEditField = uieditfield(app.Panel, 'text');
            app.plotButton = uibutton(app.Panel, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.PointPlotinMapPanel = uipanel(app.UIFigure);
            app.PointPlotinMapPanel.Title = 'Point Plot in Map';
            app.STARTLabel = uilabel(app.PointPlotinMapPanel);
            app.STARTLabel.Text = 'START';
            app.ENDLabel = uilabel(app.PointPlotinMapPanel);
            app.ENDLabel.Text = 'END';
            app.XLabel = uilabel(app.PointPlotinMapPanel);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.PointPlotinMapPanel);
            app.YLabel.Text = 'Y';
            app.X_in_MapLabel = uilabel(app.PointPlotinMapPanel);
            app.X_in_MapLabel.Text = 'X_in_Map';
            app.Y_in_MapLabel = uilabel(app.PointPlotinMapPanel);
            app.Y_in_MapLabel.Text = 'Y_in_Map';
            app.RoadNumLabel = uilabel(app.PointPlotinMapPanel);
            app.RoadNumLabel.Text = 'RoadNum';
            app.startx = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.endx = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.starty = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.endy = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.startRoadNum = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.startRoadNum.Editable = 'off';
            app.endRoadNum = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.endRoadNum.Editable = 'off';
            app.startXmap = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.startXmap.Editable = 'off';
            app.endXmap = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.endXmap.Editable = 'off';
            app.startYmap = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.startYmap.Editable = 'off';
            app.endYmap = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.endYmap.Editable = 'off';
            app.submitButton = uibutton(app.PointPlotinMapPanel, 'push');
            app.submitButton.ButtonPushedFcn = createCallbackFcn(app, @submitButtonPushed, true);
            app.submitButton.Text = 'submit';
            app.GeoNumLabel = uilabel(app.PointPlotinMapPanel);
            app.GeoNumLabel.Text = 'GeoNum';
            app.LaneNumLabel = uilabel(app.PointPlotinMapPanel);
            app.LaneNumLabel.Text = 'LaneNum';
            app.startGeoNum = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.startGeoNum.Editable = 'off';
            app.startLaneNum = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.startLaneNum.Editable = 'off';
            app.endGeoNum = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.endGeoNum.Editable = 'off';
            app.endLaneNum = uieditfield(app.PointPlotinMapPanel, 'numeric');
            app.endLaneNum.Editable = 'off';
            app.GeneratePathPlanPanel = uipanel(app.UIFigure);
            app.GeneratePathPlanPanel.Title = 'Generate Path Plan';
            app.Button = uibutton(app.GeneratePathPlanPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.StatusLampLabel = uilabel(app.GeneratePathPlanPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.GeneratePathPlanPanel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end